classdef RegCheckApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        PickAx              matlab.ui.control.UIAxes
        numEditFieldLabel   matlab.ui.control.Label
        numEditField        matlab.ui.control.NumericEditField
        NextButton          matlab.ui.control.Button
        Switch              matlab.ui.control.Switch
        GoButton            matlab.ui.control.Button
        QualitySliderLabel  matlab.ui.control.Label
        QualitySlider       matlab.ui.control.Slider
        FilterSliderLabel   matlab.ui.control.Label
        FilterSlider        matlab.ui.control.Slider
    end

    
    properties (Access = private)
        qalityList;
        PIC_NUM = 3911; % Description
    end
    
    methods (Access = private)
        
        function showIm(app)
            while app.FilterSlider.Value ~= 0 && app.qalityList(app.numEditField.Value) ~= round(app.FilterSlider.Value)
                app.numEditField.Value = app.numEditField.Value + 1;
            end
            [IT, ~, ~, IRC, thermpath, ~] = readFlir(app.numEditField.Value);
            counter = 0;
            while isempty(IRC) && counter < 10
                if app.FilterSlider.Value ~= 0
                    if app.qalityList(app.numEditField.Value) ~= round(app.FilterSlider.Value)
                        app.numEditField.Value = app.numEditField.Value + 1;
                        continue;
                    end
                end
                app.qalityList(app.numEditField.Value) = -1;
                app.saveQuality();
                app.numEditField.Value = app.numEditField.Value + 1;
                [IT, ~, ~, IRC, thermpath, ~] = readFlir(app.numEditField.Value);
                counter = counter + 1;
            end
            ITeq = histeq(IT);
            if app.Switch.Value == app.Switch.Items{1}
                imshowpair(IRC,ITeq,'Parent',app.PickAx); 
            else
                [DG, ~] = imgradient(rgb2gray(IRC),'prewitt');
                [DT, ~] = imgradient(ITeq,'prewitt');
                imshowpair(DG,DT,'Parent',app.PickAx); 
            end
            title(app.PickAx,thermpath);
            app.QualitySlider.Value = app.qalityList(app.numEditField.Value);
        end
        
        function saveQuality(app)
            qalityList = app.qalityList;
            save('qualityList.mat','qalityList');
        end
        
        function changeSlider(app)
            app.qalityList(app.numEditField.Value) = round(app.QualitySlider.Value);
            app.saveQuality();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if exist('qualityList.mat','file')
                load('qualityList.mat');
                app.qalityList = qalityList;
            else
                app.qalityList = zeros(1,app.PIC_NUM);
                app.saveQuality();
            end
            [~,i] = find(app.qalityList == 0, 1 );
            app.numEditField.Value = i;
            app.showIm();
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.showIm();
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
           app.showIm(); 
        end

        % Value changed function: QualitySlider
        function QualitySliderValueChanged(app, event)
            app.changeSlider();
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.numEditField.Value = app.numEditField.Value + 1;
            app.showIm();
        end

        % Key press function: UIFigure
        function KeyPressFcn(app, event)
            key = event.Key;
            switch key
                case '1'
                    app.QualitySlider.Value = 1;
                    app.changeSlider();
                case '2'
                    app.QualitySlider.Value = 2;
                    app.changeSlider();
                case '3'
                    app.QualitySlider.Value = 3;
                    app.changeSlider();
                case 'return'
                    app.NextButtonPushed([]);
                case 'rightarrow'
                    if strcmp(app.Switch.Value,'Dif')
                        app.Switch.Value = 'Pic';
                    else
                        app.Switch.Value = 'Dif';
                    end
                    app.showIm();
                case 'leftarrow'
                    if strcmp(app.Switch.Value,'Dif')
                        app.Switch.Value = 'Pic';
                    else
                        app.Switch.Value = 'Dif';
                    end
                    app.showIm();
                case 'uparrow'
                    app.numEditField.Value = app.numEditField.Value + 1;
                    app.showIm();
                case 'downarrow'
                    app.numEditField.Value = app.numEditField.Value - 1;
                    app.showIm();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @KeyPressFcn, true);
            app.PickAx = uiaxes(app.UIFigure);
            title(app.PickAx, 'Title')
            xlabel(app.PickAx, '')
            ylabel(app.PickAx, '')
            app.numEditFieldLabel = uilabel(app.UIFigure);
            app.numEditFieldLabel.Text = 'num';
            app.numEditField = uieditfield(app.UIFigure, 'numeric');
            app.numEditField.Value = 1;
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Pic', 'Dif'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Dif';
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.QualitySliderLabel = uilabel(app.UIFigure);
            app.QualitySliderLabel.Text = 'Quality';
            app.QualitySlider = uislider(app.UIFigure);
            app.QualitySlider.Limits = [0 3];
            app.QualitySlider.MajorTicks = [1 2 3];
            app.QualitySlider.MajorTickLabels = {'1', '2', '3'};
            app.QualitySlider.ValueChangedFcn = createCallbackFcn(app, @QualitySliderValueChanged, true);
            app.QualitySlider.MinorTicks = [];
            app.FilterSliderLabel = uilabel(app.UIFigure);
            app.FilterSliderLabel.Text = 'Filter';
            app.FilterSlider = uislider(app.UIFigure);
            app.FilterSlider.Limits = [0 3];
            app.FilterSlider.MajorTicks = [1 2 3];
            app.FilterSlider.MajorTickLabels = {'1', '2', '3'};
            app.FilterSlider.MinorTicks = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RegCheckApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end